classdef SignalAnalyzerApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        LiveSwitch                   matlab.ui.control.ToggleSwitch
        LiveSwitchLabel              matlab.ui.control.Label
        SnapDataButton               matlab.ui.control.Button
        AudioBuffersizesSlider       matlab.ui.control.Slider
        AudioBuffersizesSliderLabel  matlab.ui.control.Label
        MicrophoneSwitch             matlab.ui.control.ToggleSwitch
        MicrophoneSwitchLabel        matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
        AudioAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)

        
        sampleRate = 44100; % Sample rate in Hz
        nBits = 16; % Bits per sample
        nChannels = 1; % Number of audio channels
        sampleSpeed = 0.1;
        
        bufferSize = 1; %length in seconds

        recObj;
        myTimer;
    end
    
    methods (Access = private)


        function sampleData(app)
            
            if isempty(app.recObj) || ~isrecording(app.recObj)
                return
            end
            
            audioData = getaudiodata(app.recObj);
            
            L = app.sampleRate*app.bufferSize;
            L = round(L);

            f = app.sampleRate*(0:L/2)/L;


            sampledData = zeros(L,1);
            if length(audioData) >= length(sampledData)
                sampledData = audioData(end-L : end);
            else
                sampledData(1:length(audioData)) = audioData;
            end
        
            % fft
            Y = fft(sampledData);
            P2 = abs(Y/L);
            P1 = P2(1:L/2+1);
            P1(2:end-1) = 2*P1(2:end-1);

            t = (1:length(sampledData))/app.sampleRate;

            plot(app.AudioAxes, t, sampledData)
            semilogx(app.UIAxes, f, P1)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.myTimer = timer;
            app.myTimer.TimerFcn = @(src, event) app.sampleData();
            app.myTimer.Period = app.sampleSpeed;
            app.myTimer.ExecutionMode = 'fixedRate';
            app.myTimer.BusyMode = 'drop';
            
        end

        % Value changed function: MicrophoneSwitch
        function MicrophoneSwitchValueChanged(app, event)
            value = app.MicrophoneSwitch.Value;
            if value == "On"
                app.recObj = audiorecorder(app.sampleRate, app.nBits, app.nChannels); % audio recorder object
                record(app.recObj);
            else
                stop(app.recObj);
            end
        end

        % Button pushed function: SnapDataButton
        function SnapDataButtonPushed(app, event)
            app.sampleData()


        end

        % Value changed function: AudioBuffersizesSlider
        function AudioBuffersizesSliderValueChanged(app, event)
            value = app.AudioBuffersizesSlider.Value;
            app.bufferSize = round(value,1);
        end

        % Value changed function: LiveSwitch
        function LiveSwitchValueChanged(app, event)
            value = app.LiveSwitch.Value;
            if value == "On"
                if app.MicrophoneSwitch.Value == "On"
%                     app.myTimer.TimerFcn = @(src, event) app.sampleData();
                    start(app.myTimer);
                end
            else
                stop(app.myTimer);
            end
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            if isvalid(app.myTimer)
                stop(app.myTimer);
                delete(app.myTimer);
            end
            delete(app)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.AudioAxes = uiaxes(app.UIFigure);
            title(app.AudioAxes, 'Live Audio')
            xlabel(app.AudioAxes, 'Time (s)')
            ylabel(app.AudioAxes, 'Amplitude')
            zlabel(app.AudioAxes, 'Z')
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Live FFT')
            xlabel(app.UIAxes, 'Frequency (Hz)')
            ylabel(app.UIAxes, 'Power')
            zlabel(app.UIAxes, 'Z')
            app.MicrophoneSwitchLabel = uilabel(app.UIFigure);
            app.MicrophoneSwitchLabel.Text = 'Microphone';
            app.MicrophoneSwitch = uiswitch(app.UIFigure, 'toggle');
            app.MicrophoneSwitch.ValueChangedFcn = createCallbackFcn(app, @MicrophoneSwitchValueChanged, true);
            app.AudioBuffersizesSliderLabel = uilabel(app.UIFigure);
            app.AudioBuffersizesSliderLabel.Text = 'Audio Buffer size (s)';
            app.AudioBuffersizesSlider = uislider(app.UIFigure);
            app.AudioBuffersizesSlider.Limits = [0 5];
            app.AudioBuffersizesSlider.ValueChangedFcn = createCallbackFcn(app, @AudioBuffersizesSliderValueChanged, true);
            app.AudioBuffersizesSlider.Value = 1;
            app.SnapDataButton = uibutton(app.UIFigure, 'push');
            app.SnapDataButton.ButtonPushedFcn = createCallbackFcn(app, @SnapDataButtonPushed, true);
            app.SnapDataButton.Text = 'Snap Data';
            app.LiveSwitchLabel = uilabel(app.UIFigure);
            app.LiveSwitchLabel.Text = 'Live';
            app.LiveSwitch = uiswitch(app.UIFigure, 'toggle');
            app.LiveSwitch.ValueChangedFcn = createCallbackFcn(app, @LiveSwitchValueChanged, true);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SignalAnalyzerApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end